classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HOMEButton_2         matlab.ui.control.Button
        CONNECTButton        matlab.ui.control.Button
        DISCONNECTButton     matlab.ui.control.Button
        CALIBRATETAGSButton  matlab.ui.control.Button
        EditFieldLabel       matlab.ui.control.Label
        EditField            matlab.ui.control.EditField
        TAGPOSITIONSLabel    matlab.ui.control.Label
        EditField_2Label     matlab.ui.control.Label
        EditField_2          matlab.ui.control.EditField
        EditField_3Label     matlab.ui.control.Label
        EditField_3          matlab.ui.control.EditField
        Label                matlab.ui.control.Label
        EditField_4          matlab.ui.control.EditField
        FOLLOWMODEButton     matlab.ui.control.Button
        PICKUP1Button        matlab.ui.control.Button
        PICKUP2Button        matlab.ui.control.Button
        PICKUP3Button        matlab.ui.control.Button
        PICKUP4Button        matlab.ui.control.Button
        PLOTTAGSButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        robot1;
        AR1;
        
    end
    
    methods (Access = private)
        
        function UpdatePosition(app)
            
            tagPositions = app.AR1.GetTagPositions();
            
            if length(tagPositions) == 1
                app.EditField.Value = tagPositions(1);
                app.EditField_2.Value = ' ';
                app.EditField_3.Value = ' ';        
                app.EditField_4.Value = ' ';
            end
            
            if length(tagPositions) == 2
                app.EditField.Value = tagPositions(1);
                app.EditField_2.Value = tagPositions(2);
                app.EditField_3.Value = ' ';
                app.EditField_4.Value = ' ';
            end
            
            if length(tagPositions) == 3
                app.EditField.Value = tagPositions(1);
                app.EditField_2.Value = tagPositions(2);
                app.EditField_3.Value = tagPositions(3);
                app.EditField_4.Value = ' ';
            end
            
            if length(tagPositions) == 4
                app.EditField.Value = tagPositions(1);
                app.EditField_2.Value = tagPositions(2);
                app.EditField_3.Value = tagPositions(3);
                app.EditField_4.Value = tagPositions(4);
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
            rosinit;
            app.AR1 = ARControl();
            disp('Robot Ready!');
        end

        % Button pushed function: HOMEButton_2
        function HOMEButton_2Pushed(app, event)
            app.AR1.robot1.HomeDobot();
        end

        % Button pushed function: DISCONNECTButton
        function DISCONNECTButtonPushed(app, event)
            disp('Disconnecting... Bye!');
            rosshutdown;
        end

        % Button pushed function: CALIBRATETAGSButton
        function CALIBRATETAGSButtonPushed(app, event)
            app.AR1.GetARTagPositions();
            
            app.UpdatePosition();
        end

        % Button pushed function: FOLLOWMODEButton
        function FOLLOWMODEButtonPushed(app, event)
            app.AR1.FollowARTag();
        end

        % Button pushed function: PICKUP1Button
        function PICKUP1ButtonPushed(app, event)
            tagPositions = app.AR1.GetNumberOfTags();
            
            disp(tagPositions);
            
            if length(tagPositions) <= 1
                app.AR1.MoveToTag(1);
            end
        end

        % Button pushed function: PICKUP2Button
        function PICKUP2ButtonPushed(app, event)
            tagPositions = app.AR1.GetNumberOfTags();

            if length(tagPositions) <= 2
                app.AR1.MoveToTag(2);
            end
        end

        % Button pushed function: PICKUP3Button
        function PICKUP3ButtonPushed(app, event)
            tagPositions = app.AR1.GetNumberOfTags();
            
            if length(tagPositions) <= 3
                app.AR1.MoveToTag(3);
            end
        end

        % Button pushed function: PICKUP4Button
        function PICKUP4ButtonPushed(app, event)
            tagPositions = app.AR1.GetNumberOfTags();
            
            if length(tagPositions) <= 4
                app.AR1.MoveToTag(4);
            end
        end

        % Button pushed function: PLOTTAGSButton
        function PLOTTAGSButtonPushed(app, event)
            app.AR1.TRPlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HOMEButton_2 = uibutton(app.UIFigure, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButton_2Pushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @DISCONNECTButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.CALIBRATETAGSButton = uibutton(app.UIFigure, 'push');
            app.CALIBRATETAGSButton.ButtonPushedFcn = createCallbackFcn(app, @CALIBRATETAGSButtonPushed, true);
            app.CALIBRATETAGSButton.Text = {'CALIBRATE '; 'TAGS'};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '1:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TAGPOSITIONSLabel = uilabel(app.UIFigure);
            app.TAGPOSITIONSLabel.Text = 'TAG POSITIONS';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '2:';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '3:';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '4:';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.FOLLOWMODEButton = uibutton(app.UIFigure, 'push');
            app.FOLLOWMODEButton.ButtonPushedFcn = createCallbackFcn(app, @FOLLOWMODEButtonPushed, true);
            app.FOLLOWMODEButton.Text = {'FOLLOW'; 'MODE'};
            app.PICKUP1Button = uibutton(app.UIFigure, 'push');
            app.PICKUP1Button.ButtonPushedFcn = createCallbackFcn(app, @PICKUP1ButtonPushed, true);
            app.PICKUP1Button.Text = {'PICK UP'; '1'};
            app.PICKUP2Button = uibutton(app.UIFigure, 'push');
            app.PICKUP2Button.ButtonPushedFcn = createCallbackFcn(app, @PICKUP2ButtonPushed, true);
            app.PICKUP2Button.Text = {'PICK UP'; '2'};
            app.PICKUP3Button = uibutton(app.UIFigure, 'push');
            app.PICKUP3Button.ButtonPushedFcn = createCallbackFcn(app, @PICKUP3ButtonPushed, true);
            app.PICKUP3Button.Text = {'PICK UP'; '3'};
            app.PICKUP4Button = uibutton(app.UIFigure, 'push');
            app.PICKUP4Button.ButtonPushedFcn = createCallbackFcn(app, @PICKUP4ButtonPushed, true);
            app.PICKUP4Button.Text = {'PICK UP'; '4'};
            app.PLOTTAGSButton = uibutton(app.UIFigure, 'push');
            app.PLOTTAGSButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTTAGSButtonPushed, true);
            app.PLOTTAGSButton.Text = 'PLOT TAGS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end